classdef SoRoForge_20220329 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SnapButton                      matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        library                         matlab.ui.control.DropDown
        phiBox                          matlab.ui.control.Label
        equationBox                     matlab.ui.control.Label
        SimSwitch                       matlab.ui.control.Switch
        Lamp                            matlab.ui.control.Lamp
        SimB                            matlab.ui.control.Button
        SimF                            matlab.ui.control.Button
        DummyLabel3                     matlab.ui.control.Label
        NodeOrLinkVal                   matlab.ui.control.NumericEditField
        subInc                          matlab.ui.control.Button
        incVal                          matlab.ui.control.NumericEditField
        addInc                          matlab.ui.control.Button
        ParametersButton                matlab.ui.control.Button
        FabricateButton                 matlab.ui.control.Button
        SimulateButton                  matlab.ui.control.Button
        LoadNetworkButton               matlab.ui.control.Button
        SaveNameField                   matlab.ui.control.EditField
        SaveNetworkButton               matlab.ui.control.Button
        AggregationSwitch               matlab.ui.control.Switch
        AggregationSwitchLabel          matlab.ui.control.Label
        RadioGroup                      matlab.ui.container.ButtonGroup
        MoveButton                      matlab.ui.control.RadioButton
        ModifyButton                    matlab.ui.control.RadioButton
        DeleteButton                    matlab.ui.control.RadioButton
        AddInspectButton                matlab.ui.control.RadioButton
        Label                           matlab.ui.control.Label
        VisualizeButton                 matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        MAClabLogo                      matlab.ui.control.Image
        TitleBlock                      matlab.ui.control.Label
        ParametersPanel                 matlab.ui.container.Panel
        CapFacesCheckBox_2              matlab.ui.control.CheckBox
        CapFacesCheckBox                matlab.ui.control.CheckBox
        shoreHardVal                    matlab.ui.control.NumericEditField
        ShoreHardText                   matlab.ui.control.Label
        internalPressure                matlab.ui.control.NumericEditField
        PressureLabel                   matlab.ui.control.Label
        edgeLength                      matlab.ui.control.NumericEditField
        ShellEdgeLengthmmEditFieldLabel  matlab.ui.control.Label
        shellWall                       matlab.ui.control.NumericEditField
        ShellThicknessmmEditFieldLabel  matlab.ui.control.Label
        ScalarFieldExtentsmmLabel       matlab.ui.control.Label
        XYZLabel                        matlab.ui.control.Label
        MaxZ                            matlab.ui.control.NumericEditField
        MaxY                            matlab.ui.control.NumericEditField
        MinZ                            matlab.ui.control.NumericEditField
        MinY                            matlab.ui.control.NumericEditField
        MaxX                            matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinX                            matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        DX_scalarField                  matlab.ui.control.NumericEditField
        ScalarFieldVoxelSizemmEditFieldLabel  matlab.ui.control.Label
        NetworkAxes                     matlab.ui.control.UIAxes
        FingerAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        rsize = 0.03;
        movenode = [];
        clicktol = 0.0008;            % tolerance for selecting an object
        L_temp = [];                % list of start and end node for link 
        marker1 = [];                    % handle for rectangle that shows node selection
        marker2 = [];                    % handle for circle that shows link selection
        marker3 = [];                    % handle for circle that shows link selection

        
        %storage for geometry network
        G_N  = {'x','y','z','r','th'};        % list of the nodes names currently in the network
        G_Nb = [0 0 0 0 0];                   % list of the node biases
        G_agg = {'+','+','+','+','+'};        % list of aggregation functions for each node
        G_s = [];                           % source nodes in link list
        G_t = [];                     % target nodes in link list
        G_w = [];                     % weights in link list
        G_Rpos = [];
        
        R = {};                     % list of rectangle handles
        L = {};                     % list of link handles
        L2 = {};                    % list of link dot handles
        Ld = [];                    % list of link centerpoint locations

        modN = [];
        modL = [];

        simi = 0;
        Vsim = [];
        V1 = [];
        Fsim = [];
        simP = [];


        Green = [0.38,0.80,0.38];
        Orange =[255,127,80]./255;
    end
    
    methods (Access = private)
        
        function [selectedNode] = findeNearestNode(app,pos)
            dist = sum((app.G_Rpos-pos).^2,2);
            [minD, selectedNode] = min(dist);
            if minD>app.clicktol
                selectedNode = [];
            end
         end
         
        function [selectedLink] = findeNearestLink(app,pos)
            if ~isempty(app.Ld)
                dist = sum((app.Ld-pos).^2,2);
                [minD, selectedLink] = min(dist);
                if minD<app.clicktol
                    return
                end
            end
            selectedLink = [];
         end
         
        function [] = addNode(app,pos)
            
            name = app.DropDown.Value;

            clor = brewermap(12,'Set3');
            if contains(name,'Identity')
                color = clor(1,:);
            elseif contains(name,'Sine')
                color = clor(2,:);
            elseif contains(name,'Cosine')
                color = clor(3,:);
            elseif contains(name,'Atan')
                color = clor(4,:);
            elseif contains(name,'Squared')
                color = clor(5,:);
            elseif contains(name,'Fourth')
                color = clor(6,:);
            elseif contains(name,'Inv')
                color = clor(7,:);
            elseif contains(name,'Sqrt')
                color = clor(8,:);
            end

            index = length(app.R)+1;
            app.R{index} = rectangle(app.NetworkAxes,'position',...
                [pos-app.rsize app.rsize app.rsize],'curvature',0.3,'FaceColor',color,"EdgeColor",'none');
            
            app.G_N = [app.G_N name];
            app.G_Nb = [app.G_Nb 0];
            app.G_agg = [app.G_agg app.AggregationSwitch.Value];
            app.G_Rpos = [app.G_Rpos; pos;];            
        end
        
        function [] = deleteNode(app,ID)
            
        % only delete if we aren't clicking one of the five base nodes
            if ID>5    
                delete(app.R{ID}); app.R(ID) = [];
                
                % remove a node from the geometry network
                app.G_N(ID) = []; app.G_Nb(ID) = []; app.G_agg(ID) = []; app.G_Rpos(ID,:) = [];

                % update the nodes in the link list
                app.G_s(app.G_s>ID) = app.G_s(app.G_s>ID)-1; 
                app.G_t(app.G_t>ID) = app.G_t(app.G_t>ID)-1;
            end
        end
        
        function [] = addLink(app)
            
            sn = app.R{app.L_temp(1)}.Position(1:2) + [app.rsize app.rsize]/2;
            tn = app.R{app.L_temp(2)}.Position(1:2) + [app.rsize app.rsize]/2;
            
            offset = app.rsize;
            
            full_line = tn-sn;
            
            factor = offset/norm(full_line);
            
            sn = sn + factor*full_line;
            tn = tn - factor*full_line;
            
            i = length(app.L)+1;
            
            hold(app.NetworkAxes,'on')
            hs = 0.12/norm(full_line);
            app.L{i} = quiver(app.NetworkAxes,sn(1),sn(2),tn(1)-sn(1),tn(2)-sn(2),0,'LineWidth',1.5,'color','k',"MaxHeadSize",hs);
            app.L2{i} = plot(app.NetworkAxes,mean([sn(1) tn(1)]),mean([sn(2) tn(2)]),'k.','MarkerSize',20);
            app.Ld = [app.Ld; mean([sn(1) tn(1)]) mean([sn(2) tn(2)])];           

            app.G_s(end+1) = app.L_temp(1);
            app.G_t(end+1) = app.L_temp(2);
            app.G_w(end+1) = 1;
            
            app.L_temp = [];
            
        end
        
        function [] = deleteLink(app,ID)
            for i = 1:length(ID)
                delete(app.L{ID(i)})
                delete(app.L2{ID(i)})
            end
            app.L(ID) = [];
            app.L2(ID) = [];
            app.Ld(ID,:) = [];
            
            app.G_s(ID) = [];
            app.G_t(ID) = [];
            app.G_w(ID) = [];

        end
        
        function [] = insertNode(app,pos,ID)
            
            b_temp = app.NodeOrLinkVal.Value;
            
            addNode(app,pos)
            
            app.NodeOrLinkVal.Value = app.G_w(ID);
            app.L_temp = [app.G_s(ID) length(app.G_N)];
            addLink(app)
            app.L_temp = [length(app.G_N) app.G_t(ID)];
            addLink(app)
            deleteLink(app,ID)
            
            app.NodeOrLinkVal.Value = b_temp;
        end
        
        function go = checkNetwork(app)
            go = 1;
            G = digraph(app.G_s,app.G_t,app.G_w);
            if ~isdag(G) || isempty(G.Edges)
                go = 0;
            end
        end
        
        function [] = redrawNetwork(app)
            
            %draw the legend in the corner
            LPT = 0.035:0.04:0.5;
            LPS = 0.02:0.04:0.4;
            T = {'Identity','Sine','Cosine','Atan','Squared','Fourth','Inv','Sqrt'};
            clor = brewermap(12,'Set3');

            for i = 1:length(T)
            rectangle(app.NetworkAxes,'position',[0.02 LPS(i) app.rsize app.rsize],...
                'curvature',0.3,'FaceColor',clor(i,:),"EdgeColor",'none')
            text(app.NetworkAxes,0.06,LPT(i),T(i),'FontName','Corbel')
            end
            
            %Draw the X, Y, Z, Theta, R rectangle
            T = {'X','Y','Z','\Theta','R'};
            for i = 1:5
            app.R{i} = rectangle(app.NetworkAxes,'position',...
                [app.G_Rpos(i,:)-app.rsize/2 app.rsize app.rsize],...
                'curvature',0.3,'FaceColor',[0.9 0.9 0.9],"EdgeColor",[0 0 0],'LineWidth',1.5);
            text(app.NetworkAxes,app.G_Rpos(i,1),0.9,T(i),'FontName','Corbel','HorizontalAlignment','center')
            end

            if length(app.G_N)>5
                %draw the rest of the rectangles
                for i = 6:length(app.G_N)
                    if contains(app.G_N{i},'Identity')
                        color = clor(1,:);
                    elseif contains(app.G_N{i},'Sine')
                        color = clor(2,:);
                    elseif contains(app.G_N{i},'Cosine')
                        color = clor(3,:);
                    elseif contains(app.G_N{i},'Atan')
                        color = clor(4,:);
                    elseif contains(app.G_N{i},'Squared')
                        color = clor(5,:);
                    elseif contains(app.G_N{i},'Fourth')
                        color = clor(6,:);
                    elseif contains(app.G_N{i},'Inv')
                        color = clor(7,:);
                    elseif contains(app.G_N{i},'Sqrt')
                        color = clor(8,:);
                    end
                    app.R{i} = rectangle(app.NetworkAxes,'position',[app.G_Rpos(i,:)-app.rsize/2 app.rsize app.rsize],'curvature',0.3,'FaceColor',color,"EdgeColor",'none');
                end
            end
                
            %draw all the links
            if ~isempty(app.G_s)
                hold(app.NetworkAxes,'on')
                for i = 1:length(app.G_s)
                    sn = app.R{app.G_s(i)}.Position(1:2) + [app.rsize app.rsize]/2;
                    tn = app.R{app.G_t(i)}.Position(1:2) + [app.rsize app.rsize]/2;         
                    full_line = tn-sn;
                    factor = app.rsize/norm(full_line);
                    sn = sn + factor*full_line;
                    tn = tn - factor*full_line;
                    hs = 0.12/norm(full_line);
                    app.L{i}=quiver(app.NetworkAxes,sn(1),sn(2),tn(1)-sn(1),tn(2)-sn(2), ...
                        0,'LineWidth',1.5,'color','k',"MaxHeadSize",hs);
                    app.L2{i}=plot(app.NetworkAxes,mean([sn(1) tn(1)]),mean([sn(2) tn(2)]),'k.','MarkerSize',20);
                    app.Ld = [app.Ld; mean([sn(1) tn(1)]) mean([sn(2) tn(2)])];
                end
            end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.G_Rpos = [0.2 0.85;  0.6 0.85; 1 0.85; 1.4 0.85; 1.8 0.85;];

            redrawNetwork(app);
            
            app.Label.Text = sprintf(['Click on a blank area to add a new node.  \n' ...
                    'Click on two nodes to create a directed link between them.  \n' ...
                    'Click on the centerpoint of an existing link to insert a node there. \n'...
                    'Hover over a node or link to check its bias or weight.']);
            

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            %cursorposition
            CP = event.Source.CurrentPoint;
            
            %NetworkPlotPostion
            NPP = app.NetworkAxes.Position;
                                                            
            if CP(1)>NPP(1) && CP(1)<NPP(3)+NPP(1) && CP(2)>NPP(2) && CP(2)<NPP(4)+NPP(2)
                
                set(app.UIFigure,'Pointer','crosshair');
                
                delete(app.marker1)
                delete(app.marker2)

                %determine the XY coords of the cursor position
                X = app.NetworkAxes.XLim;
                Y = app.NetworkAxes.YLim;
                pos2(1)= X(1) + diff(X)/diff(NPP([1 3]))*(CP(1)-NPP(1));
                pos2(2)= Y(1) + diff(Y)/diff(NPP([2 4]))*(CP(2)-NPP(2));

                ID1 = findeNearestNode(app,pos2);
                ID2 = findeNearestLink(app,pos2);

                if ~isempty(ID1)
                    
                    app.marker1 = rectangle(app.NetworkAxes,'position',[app.R{ID1}.Position(1:2)-0.005 app.rsize+0.01 app.rsize+0.01],'curvature',0.3,'FaceColor','none','EdgeColor','r','LineWidth',1.5);
                    app.DummyLabel3.Text = 'Node Bias';
                    
                    %app.NodeOrLinkVal.Value = app.G_Nb(ID1);
                    %app.AggregationSwitch.Value = app.G_agg{ID1};
                    
                end
                
                if ~isempty(ID2)
                    
                    app.marker2 = rectangle(app.NetworkAxes,'position',[app.Ld(ID2,:)-0.015 0.03 0.03],'curvature',1,'FaceColor','none','EdgeColor','r','linewidth',1.5);
                    app.DummyLabel3.Text = 'Link Weight';
                    
                    %app.NodeOrLinkVal.Value = app.G_w(ID2);
                end
                  
            else
                    set(app.UIFigure,'Pointer','arrow');
            end
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
            %cursorposition
            CP = event.Source.CurrentPoint;
            %NetworkPlotPostion
            NPP = app.NetworkAxes.Position;
            
            %if we are inside the network graph window
            if CP(1)>NPP(1) && CP(1)<NPP(3)+NPP(1) && CP(2)>NPP(2) && CP(2)<NPP(4)+NPP(2)
                
                %determine the XY coords of the cursor position
                X = app.NetworkAxes.XLim;
                Y = app.NetworkAxes.YLim;

                pos(1)= X(1) + diff(X)/diff(NPP([1 3]))*(CP(1)-NPP(1));
                pos(2)= Y(1) + diff(Y)/diff(NPP([2 4]))*(CP(2)-NPP(2));

                [IDN] = findeNearestNode(app,pos);
                [IDL] = findeNearestLink(app,pos);

                app.modL = [];
                app.modN = [];

                delete(app.marker3)
                
                if app.AddInspectButton.Value
                    %in ADD mode, if we click on a node, then we add that
                    %node to the list L_temp.If we have two nodes in the list L_temp,
                    %then we draw a link between them
                    if ~isempty(IDN)
                        if isempty(app.L_temp)
                            app.L_temp = IDN;
                            app.marker3 = rectangle(app.NetworkAxes,'position',[app.R{IDN}.Position(1:2)-0.01 app.rsize+0.02 app.rsize+0.02],'curvature',0.3,'FaceColor','none','EdgeColor','b','LineWidth',1.5);
                        else %we must have exactly one entry
                            if IDN ~= app.L_temp(1)
                                delete(app.marker3)
                                app.L_temp = [app.L_temp IDN];                            
                                addLink(app)
                                app.L_temp = [];
                            end
                        end

                    %in ADD mode, if we click on a link, we insert a node
                    %at that point in the link.
                    elseif ~isempty(IDL)
                        insertNode(app,pos,IDL)
                    %if neither of the above are true, we add a node at the
                    %position of the click
                    else
                        addNode(app,pos)
                    end

                elseif app.DeleteButton.Value
                    %in delete mode, if we click a node, then we delete all
                    %the links connected to that node first, then we delete
                    if ~isempty(IDN)
                        DL = [reshape(find(app.G_s==IDN),[],1); reshape(find(app.G_t==IDN),[],1)];
                        deleteLink(app,DL)     
                        deleteNode(app,IDN)
                    %in DELETE mode, if we click a link , we simply delete
                    elseif ~isempty(IDL)
                        delete(app.marker2)
                        deleteLink(app,IDL)
                    end

                elseif app.ModifyButton.Value
                    if ~isempty(IDN)
                        app.marker3 = rectangle(app.NetworkAxes,'position',[app.R{IDN}.Position(1:2)-0.01 app.rsize+0.02 app.rsize+0.02],'curvature',0.3,'FaceColor','none','EdgeColor','b','LineWidth',1.5);
                        app.modN = IDN;
                        app.NodeOrLinkVal.Value = app.G_Nb(IDN);
                    elseif ~isempty(IDL)
                        app.marker3 = rectangle(app.NetworkAxes,'position',[app.Ld(IDL,:)-0.0222 0.045 0.045],'curvature',1,'FaceColor','none','EdgeColor','b','linewidth',1.5);
                        app.modL = IDL;
                        app.NodeOrLinkVal.Value = app.G_w(IDL);
                    end 

                else
                    if ~isempty(IDN)
                        app.movenode = IDN;
                    end
                end
            end
        end

        % Selection changed function: RadioGroup
        function RadioGroupSelectionChanged(app, event)

            delete(app.marker3)
            app.L_temp = [];
            app.incVal.Visible = 'off';
            app.addInc.Visible = 'off';
            app.subInc.Visible = 'off';

            if app.AddInspectButton.Value
                app.Label.Text = sprintf(['Click on a blank area to add a new node.  \n' ...
                    'Click on two nodes to create a directed link between them.  \n' ...
                    'Click on the centerpoint of an existing link to insert a node there. \n'...
                    'Hover over a node or link to check its bias or weight.']);
            elseif app.DeleteButton.Value
                app.Label.Text = 'Click on any existing node or link to delete it from the network.';
            elseif app.ModifyButton.Value
                app.incVal.Visible = 'on';
                app.addInc.Visible = 'on';
                app.subInc.Visible = 'on';
                app.Label.Text = sprintf(['Select a node or link. \n'...
                    'Then adjust its value. \n\n']);
            else
                app.Label.Text = sprintf(['Click on a node, then use the arrow keys to adjust its position.']);
            end

        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            go = checkNetwork(app);
            
            if go
                app.Lamp.Color = app.Orange;

                cla(app.FingerAxes)
                G = digraph(app.G_s,app.G_t,app.G_w);

                Extents = [app.MinX.Value app.MaxX.Value; app.MinY.Value app.MaxY.Value; app.MinZ.Value app.MaxZ.Value;];
                Extents = Extents./10;

                CapVal = 0;
                if app.CapFacesCheckBox.Value
                    CapVal =  1e3;
                end
                if app.CapFacesCheckBox_2.Value
                    CapVal = -1e3;
                end

                Params = setParams('IsoVal',0,'Extents',Extents,...
                    'dx',app.DX_scalarField.Value/10,'ElementSize',app.edgeLength.Value,...
                    'MembraneThickness',app.shellWall.Value,'Remesh',false,'Keep',true,...
                    'CapFaces',CapVal);
                           
                for i = 1:length(app.G_N)
                    if contains(app.G_agg{i},'x') && ~contains(app.G_N{i},'*')
                        app.G_N{i} = strcat(app.G_N{i},'*');
                    end
                end

                Net.s = app.G_s; Net.t = app.G_t; Net.w = app.G_w; Net.b = app.G_Nb; Net.n = app.G_N;
                phi = net2field(Net,Params);
                [F,V] = field2mesh(phi,Params);

                functext = net2func(Net);
                app.equationBox.Text = functext;

                clor = [0.9216    0.7529    0.3686];
                patch(app.FingerAxes,'Vertices',V,'Faces',F,'facecolor',clor,'edgealpha',0)
                %view(app.FingerAxes,3); 
                set(app.FingerAxes,'DataAspectRatio',[1 1 1])
                camlight(app.FingerAxes); material(app.FingerAxes,'dull')
                app.Lamp.Color = app.Green;

            end       
        end

        % Callback function
        function GeomMatSwitchValueChanged(app, event)
            cla(app.NetworkAxes)
            app.R = {};                     % list of rectangle handles
            app.L = {};                     % list of link handles
            app.L2 = {};                    % list of link dot handles
            app.Ld = [];                    % list of link centerpoint locations
            redrawNetwork(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if ~isempty(app.movenode) && app.MoveButton.Value
                if strcmp(key,'leftarrow')
                    app.R{app.movenode}.Position(1) = app.R{app.movenode}.Position(1) - 0.025;
                    app.G_Rpos(app.movenode,1) = app.G_Rpos(app.movenode,1) - 0.025;
                elseif strcmp(key,'rightarrow')
                    app.R{app.movenode}.Position(1) = app.R{app.movenode}.Position(1) + 0.025;
                    app.G_Rpos(app.movenode,1) = app.G_Rpos(app.movenode,1) + 0.025;
                elseif strcmp(key,'uparrow')
                    app.R{app.movenode}.Position(2) = app.R{app.movenode}.Position(2) + 0.025;
                    app.G_Rpos(app.movenode,2) = app.G_Rpos(app.movenode,2) + 0.025;
                elseif strcmp(key,'downarrow')
                    app.R{app.movenode}.Position(2) = app.R{app.movenode}.Position(2) - 0.025;
                    app.G_Rpos(app.movenode,2) = app.G_Rpos(app.movenode,2) - 0.025;
                end


                D = [reshape(find(app.G_t==app.movenode),[],1);...
                    reshape(find(app.G_s==app.movenode),[],1)];

                for i = 1:length(D)
                    sn = app.R{app.G_s(D(i))}.Position(1:2) + [app.rsize app.rsize]/2;
                    tn = app.R{app.G_t(D(i))}.Position(1:2) + [app.rsize app.rsize]/2;         
                    offset = app.rsize;
                    full_line = tn-sn;
                    hs = 0.12/norm(full_line);
                    factor = offset/norm(full_line);
                    sn = sn + factor*full_line;
                    tn = tn - factor*full_line;
                    app.L{D(i)}.XData = sn(1);
                    app.L{D(i)}.YData = sn(2);
                    app.L{D(i)}.UData = tn(1)-sn(1);
                    app.L{D(i)}.VData = tn(2)-sn(2);
                    app.L{D(i)}.MaxHeadSize = hs;
                    app.L2{D(i)}.XData = (sn(1)+tn(1))/2;
                    app.L2{D(i)}.YData = (sn(2)+tn(2))/2;
                    app.Ld(D(i),:) = [(sn(1)+tn(1))/2 (sn(2)+tn(2))/2];
                end
            end
        end

        % Button pushed function: SaveNetworkButton
        function SaveNetworkButtonPushed(app, event)
            
            %Write out both the material and geometry networks
            for i = 1:length(app.G_N)
                if contains(app.G_agg{i},'x') && ~contains(app.G_N{i},'*')
                    app.G_N{i} = strcat(app.G_N{i},'*');
                end
            end
                
            Finger.Geom.Nodes = app.G_N;
            Finger.Geom.n = makeNodeNamesUnique(app.G_N);
            Finger.Geom.b = app.G_Nb;
            Finger.Geom.s = app.G_s;
            Finger.Geom.t = app.G_t;
            Finger.Geom.w = app.G_w;

            Gpos = app.G_Rpos;
            
            save(app.SaveNameField.Value,'Finger','Gpos')
          
        end

        % Button pushed function: LoadNetworkButton
        function LoadNetworkButtonPushed(app, event)
            
            load([app.library.Value '.mat'])
            
            app.G_N  = Finger.Geom.n;        % list of the nodes names currently in the network
            app.G_Nb = Finger.Geom.b;         % list of the node biases
            app.G_agg = {'+','+','+','+','+'};
            for i = 4:length(app.G_N)
                if contains(app.G_N{i},'*')
                app.G_agg{i} = 'x';
                else
                app.G_agg{i} = '+';
                end 
            end
            
            app.G_Rpos = Gpos;
           
            app.G_s = Finger.Geom.s';
            app.G_t = Finger.Geom.t';
            app.G_w = Finger.Geom.w';
            
            app.R = {};                     % list of rectangle handles
            app.L = {};                     % list of link handles
            app.L2 = {};                    % list of link dot handles
            app.Ld = [];                    % list of link centerpoint locations
            app.L_temp = [];
            
            cla(app.NetworkAxes)
            redrawNetwork(app)

           
        end

        % Button pushed function: ParametersButton
        function ParametersButtonPushed(app, event)
            if strcmp(app.ParametersPanel.Visible,'off')
                app.ParametersPanel.Visible = 'on';
            else
                app.ParametersPanel.Visible = 'off';
            end
        end

        % Button pushed function: addInc
        function addIncButtonPushed(app, event)
            if app.ModifyButton.Value
                app.Lamp.Color = app.Orange;
                if ~isempty(app.modN)
                    app.G_Nb(app.modN) = app.G_Nb(app.modN) + app.incVal.Value; 
                    VisualizeButtonPushed(app, event)
                    app.NodeOrLinkVal.Value = app.G_Nb(app.modN);
                end
                if ~isempty(app.modL)
                    app.G_w(app.modL) = app.G_w(app.modL) + app.incVal.Value; 
                    VisualizeButtonPushed(app, event)
                    app.NodeOrLinkVal.Value = app.G_w(app.modL);
                end
                app.Lamp.Color = app.Green;
            end
        end

        % Button pushed function: subInc
        function subIncButtonPushed(app, event)
            if app.ModifyButton.Value
                app.Lamp.Color = app.Orange;
                if ~isempty(app.modN)
                    app.G_Nb(app.modN) = app.G_Nb(app.modN) - app.incVal.Value; 
                    VisualizeButtonPushed(app, event)
                    app.NodeOrLinkVal.Value = app.G_Nb(app.modN);
                end
                if ~isempty(app.modL)
                    app.G_w(app.modL) = app.G_w(app.modL) - app.incVal.Value; 
                    VisualizeButtonPushed(app, event)
                    app.NodeOrLinkVal.Value = app.G_w(app.modL);
                end
                app.Lamp.Color = app.Green;
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
        if ~isempty(app.Fsim)

            mats = ones(size(app.Fsim,1),1); mats(1) = 2;
            app.Lamp.Color = app.Orange;
            P = app.internalPressure.Value;
            [app.Fsim, app.Vsim] = run_febio_disp_basic(app.Fsim,app.V1,mats,P);

            if ~isempty(app.Vsim)
                app.SimB.Visible = 'on'; app.SimF.Visible = 'on'; app.SnapButton.Visible = 'on';
            end        
            app.Lamp.Color = app.Green;
            
        end
        end

        % Button pushed function: SimF
        function SimFButtonPushed(app, event)
            app.simi = app.simi +1;
            if app.simi > size(app.Vsim,3)
                app.simi = size(app.Vsim,3);
            end
            delete(app.simP)
            clor = [152 251 152]./255;
            app.simP = patch(app.FingerAxes,'Vertices',app.Vsim(:,:,app.simi),'Faces',app.Fsim,'facecolor',clor,'linewidth',0.5);

        end

        % Button pushed function: SimB
        function SimBButtonPushed(app, event)
            app.simi = app.simi - 1;
            if app.simi < 0
                app.simi = 0;
            end
            delete(app.simP)
            clor = [152 251 152]./255;
            if size(app.Vsim,3)>0 && app.simi>0
                app.simP = patch(app.FingerAxes,'Vertices',app.Vsim(:,:,app.simi),'Faces',app.Fsim,'facecolor',clor,'linewidth',0.5);
            end
        end

        % Value changed function: SimSwitch
        function SimSwitchValueChanged(app, event)
            value = app.SimSwitch.Value;
            if strcmp(value,'On')
                go = checkNetwork(app);
                app.VisualizeButton.Enable = 'off';
                app.FabricateButton.Enable = 'off';
                
                if go
                    cla(app.FingerAxes)
                    app.Lamp.Color = app.Orange;
                    app.Label.Text = 'Forming Computational Mesh';
                    Extents = [app.MinX.Value app.MaxX.Value; app.MinY.Value app.MaxY.Value; app.MinZ.Value app.MaxZ.Value;]./10;
    

                    CapVal = 0;
                    if app.CapFacesCheckBox.Value
                        CapVal =  1e3;
                    end
                    if app.CapFacesCheckBox_2.Value
                        CapVal = -1e3;
                    end


                     Params = setParams('IsoVal',0,'Extents',Extents,...
                        'dx',app.DX_scalarField.Value/10,'ElementSize',app.edgeLength.Value,...
                        'MembraneThickness',app.shellWall.Value,'Remesh',true,'Keep',false,'rmIslands',false,...
                        'CapFaces',CapVal);
                               
                    for i = 1:length(app.G_N)
                        if contains(app.G_agg{i},'x')
                            app.G_N{i} = strcat(app.G_N{i},'*');
                        end
                    end
    
                    Net.s = app.G_s; Net.t = app.G_t; Net.w = app.G_w; Net.b = app.G_Nb; Net.n = app.G_N;
                    phi = net2field(Net,Params); [F,V] = field2mesh(phi,Params);
                    clor = [250 128 114]./255; patch(app.FingerAxes,'Vertices',V,'Faces',F,'facecolor',clor,'linewidth',0.5)
                    view(app.FingerAxes,3); set(app.FingerAxes,'DataAspectRatio',[1 1 1]); camlight(app.FingerAxes); material(app.FingerAxes,'dull')
                    
                    app.Fsim = F; app.V1 = V;
                    app.SimulateButton.Enable = 'on';
                    app.Lamp.Color = app.Green;
                end
            else
                cla(app.FingerAxes)
                app.SimulateButton.Enable = 'off';
                app.VisualizeButton.Enable = 'on';
                app.FabricateButton.Enable = 'on';
            end
        end

        % Value changed function: AggregationSwitch, NodeOrLinkVal
        function AggregationSwitchValueChanged(app, event)
            if app.ModifyButton.Value
                if ~isempty(app.modN)
                    app.G_agg{app.modN} = app.AggregationSwitch.Value;
                    if contains(app.G_N{app.modN},'*') && strcmp(app.G_agg{app.modN},'+') 
                        app.G_N{app.modN}=strrep(app.G_N{app.modN},'*','');
                    end
                end
            end
        end

        % Button pushed function: FabricateButton
        function FabricateButtonPushed(app, event)
            
            app.Lamp.Color = app.Orange;

            go = checkNetwork(app);
            
            if go
                app.VisualizeButton.Enable = 'off';
                app.FabricateButton.Enable = 'off';
                cla(app.FingerAxes)
                app.Label.Text = 'Forming Computational Mesh';
                Extents = [app.MinX.Value app.MaxX.Value; app.MinY.Value app.MaxY.Value; app.MinZ.Value app.MaxZ.Value;]./10;

                CapVal = 0;
                if app.CapFacesCheckBox.Value
                    CapVal =  1e3;
                end
                if app.CapFacesCheckBox_2.Value
                    CapVal = -1e3;
                end

                Params = setParams('IsoVal',0,'Extents',Extents,...
                    'dx',app.DX_scalarField.Value/10,...
                    'ElementSize',app.edgeLength.Value/2,...
                    'MembraneThickness',app.shellWall.Value,...
                    'Remesh',true,'Keep',false,'rmIslands',false,'MaxFaces',1e6,...
                    'CapFaces',CapVal);

                Params.CloseHoles = false;
                           
                for i = 1:length(app.G_N)
                    if contains(app.G_agg{i},'x')
                        app.G_N{i} = strcat(app.G_N{i},'*');
                    end
                end

                Net.s = app.G_s; Net.t = app.G_t; Net.w = app.G_w; Net.b = app.G_Nb; Net.n = app.G_N;
                phi = net2field(Net,Params); 
                [F,V] = field2mesh(phi,Params);
                clor = [200 200 200]./255; patch(app.FingerAxes,'Vertices',V,'Faces',F,'facecolor',clor,'linewidth',0.1)
                view(app.FingerAxes,3); set(app.FingerAxes,'DataAspectRatio',[1 1 1]); camlight(app.FingerAxes); material(app.FingerAxes,'dull')
                
                app.Fsim = F; app.V1 = V;
                if ~isempty(F)
                    stlwrite(triangulation(F,V),[app.SaveNameField.Value '.stl'])
                end
                app.VisualizeButton.Enable = 'on';
                app.FabricateButton.Enable = 'on';
                
            end
            app.Lamp.Color = app.Green;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path] = uigetfile('.mat');

            load([path '/' file]);

            app.G_N  = Finger.Geom.n;        % list of the nodes names currently in the network
            app.G_Nb = Finger.Geom.b;         % list of the node biases
            app.G_agg = {'+','+','+','+','+'};
            for i = 4:length(app.G_N)
                if contains(app.G_N{i},'*')
                app.G_agg{i} = 'x';
                else
                app.G_agg{i} = '+';
                end 
            end
            
            app.G_Rpos = Gpos;
           
            app.G_s = Finger.Geom.s';
            app.G_t = Finger.Geom.t';
            app.G_w = Finger.Geom.w';
            
            app.R = {};                     % list of rectangle handles
            app.L = {};                     % list of link handles
            app.L2 = {};                    % list of link dot handles
            app.Ld = [];                    % list of link centerpoint locations
            app.L_temp = [];
            
            cla(app.NetworkAxes)
            redrawNetwork(app)

        end

        % Button pushed function: SnapButton
        function SnapButtonPushed(app, event)
            if size(app.Vsim,3)>0 && app.simi>0
%                 ff = cFigure;
%                 f = gca(ff);
%                 clor = [250 128 114]./255; 
%                 patch(f,'Vertices',app.Vsim(:,:,1),'Faces',app.Fsim,...
%                     'facecolor',clor,'linewidth',0.5,'FaceAlpha',1);
%                 axis equal
%                 view(3)
%                 camlight headlight
%                 material dull
%                 set(gca,'visible','off')

                ff = cFigure;
                f = gca(ff);
                clor = [152 251 152]./255;
                patch(f,'Vertices',app.Vsim(:,:,app.simi),'Faces',app.Fsim,...
                    'facecolor',clor,'linewidth',0.5,'FaceAlpha',0.5);
                axis equal
                view(3)
                camlight headlight
                material dull
                set(gca,'visible','off')
            end
        end

        % Callback function
        function SplitButtonPushed(app, event)
            [app.simF,app.simV]  = splitNmerge(app.simF,app.V);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FingerAxes = uiaxes(app.UIFigure);
            app.NetworkAxes = uiaxes(app.UIFigure);
            app.NetworkAxes.XLim = [0 2];
            app.NetworkAxes.YLim = [0 1];
            app.NetworkAxes.ZLim = [0 1];
            app.NetworkAxes.XTick = [];
            app.NetworkAxes.YTick = [];
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.ParametersPanel.Visible = 'off';
            app.ScalarFieldVoxelSizemmEditFieldLabel = uilabel(app.ParametersPanel);
            app.ScalarFieldVoxelSizemmEditFieldLabel.Text = 'Scalar Field Voxel Size [mm]';
            app.DX_scalarField = uieditfield(app.ParametersPanel, 'numeric');
            app.DX_scalarField.Value = 2;
            app.MinEditFieldLabel = uilabel(app.ParametersPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinX = uieditfield(app.ParametersPanel, 'numeric');
            app.MinX.Value = -40;
            app.MaxEditFieldLabel = uilabel(app.ParametersPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxX = uieditfield(app.ParametersPanel, 'numeric');
            app.MaxX.Value = 40;
            app.MinY = uieditfield(app.ParametersPanel, 'numeric');
            app.MinY.Value = -40;
            app.MinZ = uieditfield(app.ParametersPanel, 'numeric');
            app.MaxY = uieditfield(app.ParametersPanel, 'numeric');
            app.MaxY.Value = 40;
            app.MaxZ = uieditfield(app.ParametersPanel, 'numeric');
            app.MaxZ.Value = 80;
            app.XYZLabel = uilabel(app.ParametersPanel);
            app.XYZLabel.Text = 'X       Y       Z ';
            app.ScalarFieldExtentsmmLabel = uilabel(app.ParametersPanel);
            app.ScalarFieldExtentsmmLabel.Text = 'Scalar Field Extents [mm]';
            app.ShellThicknessmmEditFieldLabel = uilabel(app.ParametersPanel);
            app.ShellThicknessmmEditFieldLabel.Text = 'Shell Thickness [mm]';
            app.shellWall = uieditfield(app.ParametersPanel, 'numeric');
            app.shellWall.Value = 1.6;
            app.ShellEdgeLengthmmEditFieldLabel = uilabel(app.ParametersPanel);
            app.ShellEdgeLengthmmEditFieldLabel.Text = 'Shell Edge Length [mm]';
            app.edgeLength = uieditfield(app.ParametersPanel, 'numeric');
            app.edgeLength.Value = 6;
            app.PressureLabel = uilabel(app.ParametersPanel);
            app.PressureLabel.Text = 'Internal Pressure [MPa]';
            app.internalPressure = uieditfield(app.ParametersPanel, 'numeric');
            app.internalPressure.Value = 0.01;
            app.ShoreHardText = uilabel(app.ParametersPanel);
            app.ShoreHardText.Text = 'Shore A Hardness [30-100]';
            app.shoreHardVal = uieditfield(app.ParametersPanel, 'numeric');
            app.shoreHardVal.Value = 60;
            app.CapFacesCheckBox = uicheckbox(app.ParametersPanel);
            app.CapFacesCheckBox.Text = 'CapFaces +';
            app.CapFacesCheckBox_2 = uicheckbox(app.ParametersPanel);
            app.CapFacesCheckBox_2.Text = 'CapFaces -';
            app.TitleBlock = uilabel(app.UIFigure);
            app.TitleBlock.Text = 'Computational Network Explorer';
            app.MAClabLogo = uiimage(app.UIFigure);
            app.MAClabLogo.ImageSource = 'MACLab.png';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Identity', 'Sine', 'Cosine', 'Atan', 'Squared', 'Fourth', 'Inv', 'Sqrt'};
            app.DropDown.Value = 'Identity';
            app.VisualizeButton = uibutton(app.UIFigure, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize!';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Explore network models by adding nodes and links using the controls on the left!';
            app.RadioGroup = uibuttongroup(app.UIFigure);
            app.RadioGroup.SelectionChangedFcn = createCallbackFcn(app, @RadioGroupSelectionChanged, true);
            app.AddInspectButton = uiradiobutton(app.RadioGroup);
            app.AddInspectButton.Text = 'Add / Inspect';
            app.AddInspectButton.Value = true;
            app.DeleteButton = uiradiobutton(app.RadioGroup);
            app.DeleteButton.Text = 'Delete';
            app.ModifyButton = uiradiobutton(app.RadioGroup);
            app.ModifyButton.Text = 'Modify';
            app.MoveButton = uiradiobutton(app.RadioGroup);
            app.MoveButton.Text = 'Move';
            app.AggregationSwitchLabel = uilabel(app.UIFigure);
            app.AggregationSwitchLabel.Text = 'Aggregation';
            app.AggregationSwitch = uiswitch(app.UIFigure, 'slider');
            app.AggregationSwitch.Items = {'x', '+'};
            app.AggregationSwitch.ValueChangedFcn = createCallbackFcn(app, @AggregationSwitchValueChanged, true);
            app.AggregationSwitch.Value = '+';
            app.SaveNetworkButton = uibutton(app.UIFigure, 'push');
            app.SaveNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNetworkButtonPushed, true);
            app.SaveNetworkButton.Text = 'Save Network';
            app.SaveNameField = uieditfield(app.UIFigure, 'text');
            app.SaveNameField.Value = 'my_new_network';
            app.LoadNetworkButton = uibutton(app.UIFigure, 'push');
            app.LoadNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNetworkButtonPushed, true);
            app.LoadNetworkButton.Text = 'Load Network';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Enable = 'off';
            app.SimulateButton.Text = 'Simulate!';
            app.FabricateButton = uibutton(app.UIFigure, 'push');
            app.FabricateButton.ButtonPushedFcn = createCallbackFcn(app, @FabricateButtonPushed, true);
            app.FabricateButton.Text = 'Fabricate!';
            app.ParametersButton = uibutton(app.UIFigure, 'push');
            app.ParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersButtonPushed, true);
            app.ParametersButton.Text = 'Parameters';
            app.addInc = uibutton(app.UIFigure, 'push');
            app.addInc.ButtonPushedFcn = createCallbackFcn(app, @addIncButtonPushed, true);
            app.addInc.Visible = 'off';
            app.addInc.Text = '+';
            app.incVal = uieditfield(app.UIFigure, 'numeric');
            app.incVal.Visible = 'off';
            app.incVal.Value = 0.1;
            app.subInc = uibutton(app.UIFigure, 'push');
            app.subInc.ButtonPushedFcn = createCallbackFcn(app, @subIncButtonPushed, true);
            app.subInc.Visible = 'off';
            app.subInc.Text = '-';
            app.NodeOrLinkVal = uieditfield(app.UIFigure, 'numeric');
            app.NodeOrLinkVal.ValueChangedFcn = createCallbackFcn(app, @AggregationSwitchValueChanged, true);
            app.DummyLabel3 = uilabel(app.UIFigure);
            app.DummyLabel3.Text = 'Node Bias';
            app.SimF = uibutton(app.UIFigure, 'push');
            app.SimF.ButtonPushedFcn = createCallbackFcn(app, @SimFButtonPushed, true);
            app.SimF.Visible = 'off';
            app.SimF.Text = '>>';
            app.SimB = uibutton(app.UIFigure, 'push');
            app.SimB.ButtonPushedFcn = createCallbackFcn(app, @SimBButtonPushed, true);
            app.SimB.Visible = 'off';
            app.SimB.Text = '<<';
            app.Lamp = uilamp(app.UIFigure);
            app.SimSwitch = uiswitch(app.UIFigure, 'slider');
            app.SimSwitch.ValueChangedFcn = createCallbackFcn(app, @SimSwitchValueChanged, true);
            app.equationBox = uilabel(app.UIFigure);
            app.equationBox.Text = '';
            app.phiBox = uilabel(app.UIFigure);
            app.phiBox.Text = 'phi =';
            app.library = uidropdown(app.UIFigure);
            app.library.Items = {'basic_bend', 'gyroid', 'bellows', 'Cosine', 'Atan'};
            app.library.Value = 'basic_bend';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SnapButton = uibutton(app.UIFigure, 'push');
            app.SnapButton.ButtonPushedFcn = createCallbackFcn(app, @SnapButtonPushed, true);
            app.SnapButton.Icon = 'cam.png';
            app.SnapButton.Visible = 'off';
            app.SnapButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SoRoForge_20220329

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end